classdef DetectorApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        SelectcharactertotrackLabel  matlab.ui.control.Label
        ButtonGroup                  matlab.ui.container.ButtonGroup
        UppercharacterButton         matlab.ui.control.RadioButton
        LowerCharacterButton         matlab.ui.control.RadioButton
        Switch                       matlab.ui.control.ToggleSwitch
        UIAxesMainPanel              matlab.ui.control.UIAxes
        UIAxesUpperPanel             matlab.ui.control.UIAxes
        UIAxesLowerPanel             matlab.ui.control.UIAxes
        TextArea                     matlab.ui.control.TextArea
        DetectedfacesLabel           matlab.ui.control.Label
    end


    properties (Access = private)
        Property % Description
         upperPhotoPath = 'E:\TBBT Training Set Data\shel3.png';
         lowerPhotoPath = 'E:\TBBT Training Set Data\how.png';
         videoExample = 'E:\TBBT Training Set Data\video_test11.mp4';
         detectedPointsUpperPhoto;
         detectedPointsLowerPhoto;
         detectedPointsVideo;
         flagSelectedPhotoNumber = 1;
         facesD;
         bboxApp;
         bboxPointsArray;
         videoFileReader;
         videoFrame;
    end

    methods (Access = private)
    
        function results = detectFromVideoStart(app)
            
            faceDetector = vision.CascadeObjectDetector();  
            faceDetector.MergeThreshold = 10;
            app.videoFileReader = vision.VideoFileReader(app.videoExample);       
            app.videoFrame      = step(app.videoFileReader);
            bbox            = step(faceDetector, app.videoFrame);
            
            disp("bbox: ");
            disp(isempty(bbox));
            disp(bbox);
            while true
                
                while (isempty(bbox) == 1)
                    disp("No faces detectd in this frame (bbox) ");
                    app.videoFrame      = step(app.videoFileReader);
                    bbox            = step(faceDetector, app.videoFrame);
                end
                
            disp("after  if ");            
            app.videoFrame = insertShape(app.videoFrame, 'Rectangle', bbox);           
            imshow(app.videoFrame, 'Parent', app.UIAxesMainPanel);           
            
          
%             bboxPoints = bbox2points(bbox(1, :));
            bboxPoints = bbox2points(bbox(1, :));

            sizeHolder = size(bbox);
            numberOfDetectedFaces = sizeHolder(1);
            disp("numberOfDetectedFaces: ");
            disp(numberOfDetectedFaces);

            app.bboxPointsArray = {};
            for i=1:numberOfDetectedFaces
               app.bboxPointsArray{i} = bbox2points(bbox(i, :));
            end

            % Detect feature points in the face region.
             faces = {};
             for i = 1:numberOfDetectedFaces
                 face = detectMinEigenFeatures(rgb2gray(app.videoFrame), 'ROI', bbox(i,:));
                 disp("in loop - face:");
                 disp(face.Count);
                 if eq(face.Count, 0) == 1
                     disp("face 000000");                             %  go back to face detector method here in some kind of loop
                     app.videoFrame      = step(app.videoFileReader);
                     bbox            = step(faceDetector, app.videoFrame);
                 else
                     faces{i} = face;  
                     disp("faces points recognized");
                 end
%                    faces{i} = face;   
             end 
             disp("faces0");
             disp(faces);
              if  isempty(bbox) == 0 && isempty(faces) == 0
                 break;
             end
           end
                         
             
% loop by array of detected faces, draw first X in the panle next for user to select one to track             
             disp("bbox array 1 content: ");
             disp(app.bboxPointsArray{1});
             disp("selected image");
             disp(app.flagSelectedPhotoNumber);
            
            
             disp("size of bbox: ");
             disp(size(app.bboxPointsArray, 2));
            
             arrayOfPanels = [ app.UIAxesUpperPanel app.UIAxesLowerPanel];
             disp("arrayOfPanels size");
            
             iteratorBorder = 0;
              if size(arrayOfPanels, 2) < numberOfDetectedFaces       
                    iteratorBorder = size(arrayOfPanels, 2);                                                                    
                else
                    iteratorBorder = numberOfDetectedFaces;
             end          
             disp(size(arrayOfPanels, 2));
            
             for i = 1:iteratorBorder                      % should iterate over numer of panels avaialble to display                            
                 newImage = imcrop(app.videoFrame, bbox(i,:));
                 imshow( newImage, 'Parent', arrayOfPanels(i));
             end   
            
             disp("faces");
             disp(faces);
                                         
             app.facesD = faces;
        
        end
        
        
        function results = trackSelectedDace(app)
            
                disp("app.flagSelectedPhotoNumber");
                disp(app.flagSelectedPhotoNumber);
            
%                 faceDetector = vision.CascadeObjectDetector();    
%                  videoFileReader = vision.VideoFileReader(app.videoExample);       
%                  videoFrame      = step(videoFileReader);
%                 bbox            = step(faceDetector, videoFrame);
            
                pointTracker = vision.PointTracker('MaxBidirectionalError', 2);
        
                face = app.facesD{app.flagSelectedPhotoNumber};
                facePointsArray = face.Location;
                disp("face point array: ");
                disp(facePointsArray);
                initialize(pointTracker, facePointsArray, app.videoFrame);
         
                videoPlayer  = vision.VideoPlayer('Position',...
                          [100 100 [size(app.videoFrame, 2), size(app.videoFrame, 1)]+30]);
        
                
                oldPoints = facePointsArray;
                
                
                while ~isDone(app.videoFileReader)
            
                     app.videoFrame = step(app.videoFileReader);
                     [AllPointsForFaces, isFound] = step(pointTracker, app.videoFrame);   
                     visiblePoints = AllPointsForFaces(isFound, :);  
                     oldInliers = oldPoints(isFound, :);
             
                     if size(visiblePoints, 1) >= 2 % need at least 2 points
                 
                 
                     [xform, oldInliers, visiblePoints] = estimateGeometricTransform(...
                         oldInliers, visiblePoints, 'similarity', 'MaxDistance', 4);
                     
                         
                         app.bboxPointsArray{app.flagSelectedPhotoNumber} = transformPointsForward(xform, app.bboxPointsArray{app.flagSelectedPhotoNumber});
                             
                        
                         bboxPolygon = reshape(app.bboxPointsArray{app.flagSelectedPhotoNumber}', 1, []);
                         app.videoFrame = insertShape(app.videoFrame, 'Polygon', bboxPolygon, ...
                             'LineWidth', 2);
                             
                       
                         app.videoFrame = insertMarker(app.videoFrame, visiblePoints, '+', ...
                             'Color', 'white');       
                     
                         % Reset the points
                         oldPoints = visiblePoints;
                         setPoints(pointTracker, oldPoints);   
                     
                    end
             
            
                 step(videoPlayer, app.videoFrame);
                 end
                                   
                release(app.videoFileReader);
                release(videoPlayer);
                release(pointTracker);
        end       

        function results = findFacePoints(app)           
            faceDetector = vision.CascadeObjectDetector();    
            app.videoFileReader = vision.VideoFileReader(app.videoExample);       
            app.videoFrame      = step(app.videoFileReader);
            app.bboxApp            = step(faceDetector, app.videoFrame);
        end
        
    end

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
              detectFromVideoStart(app);        
        end

        % Selection changed function: ButtonGroup
        function ButtonGroupSelectionChanged(app, event)
            selectedButton = app.ButtonGroup.SelectedObject;            
            if selectedButton == app.UppercharacterButton       
                app.flagSelectedPhotoNumber = 1;                             
            elseif selectedButton == app.LowerCharacterButton                        
                app.flagSelectedPhotoNumber = 2;                               
            else
                disp("none");
            end
        end

        % Value changed function: Switch
        function SwitchValueChanged(app, event)
            value = app.Switch.Value;
            app.TextArea.Value = value;
            if 1 == strcmp(value, 'START')
                trackSelectedDace(app);                                
            elseif 1 == strcmp(value, 'STOP')              
                 closereq;             
            end
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.SelectcharactertotrackLabel = uilabel(app.UIFigure);
            app.SelectcharactertotrackLabel.Text = 'Select character to track';
            app.ButtonGroup = uibuttongroup(app.UIFigure);
            app.ButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ButtonGroupSelectionChanged, true);
            app.UppercharacterButton = uiradiobutton(app.ButtonGroup);
            app.UppercharacterButton.Text = 'Upper character';
            app.UppercharacterButton.Value = true;
            app.LowerCharacterButton = uiradiobutton(app.ButtonGroup);
            app.LowerCharacterButton.Text = 'Lower Character';
            app.Switch = uiswitch(app.UIFigure, 'toggle');
            app.Switch.Items = {'STOP', 'START'};
            app.Switch.ValueChangedFcn = createCallbackFcn(app, @SwitchValueChanged, true);
            app.Switch.Value = 'STOP';
            app.UIAxesMainPanel = uiaxes(app.UIFigure);
            app.UIAxesUpperPanel = uiaxes(app.UIFigure);
            app.UIAxesLowerPanel = uiaxes(app.UIFigure);
            app.TextArea = uitextarea(app.UIFigure);
            app.DetectedfacesLabel = uilabel(app.UIFigure);
            app.DetectedfacesLabel.Text = 'Detected faces';
        end
    end

    methods (Access = public)

        % Construct app
        function app = DetectorApp

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end